classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ShowHistogramOutputButton   matlab.ui.control.Button
        ShowHistogramInputButton    matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        EnhancementTab              matlab.ui.container.Tab
        ContrastStretchingButton    matlab.ui.control.Button
        CitraNegatifButton          matlab.ui.control.Button
        TransformasiPangkatPanel    matlab.ui.container.Panel
        TransformButton_2           matlab.ui.control.Button
        gammaEditField              matlab.ui.control.NumericEditField
        gammaEditFieldLabel         matlab.ui.control.Label
        cEditField_2                matlab.ui.control.NumericEditField
        cEditField_2Label           matlab.ui.control.Label
        TransformasiLogPanel        matlab.ui.container.Panel
        TransformButton             matlab.ui.control.Button
        rEditField                  matlab.ui.control.NumericEditField
        rEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        cEditFieldLabel             matlab.ui.control.Label
        ImageBrighteningPanel       matlab.ui.container.Panel
        BrightenButton              matlab.ui.control.Button
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        HistEqTab                   matlab.ui.container.Tab
        StartHistogramEqualizationButton  matlab.ui.control.Button
        HistSpecTab                 matlab.ui.container.Tab
        StartHistogramSpecificationButton  matlab.ui.control.Button
        BrowseReferenceImageButton  matlab.ui.control.Button
        BrowseImageButton           matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % Description
        isImageRGB % Description
        Output % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            global img;
            [filename,pathname] = uigetfile({'*.bmp'}, 'Open Input Image');
            fullpathname = strcat(pathname, filename);
            img = imread(fullpathname);
            app.Image = img;
            [x,y,z] = size(app.Image);
            app.isImageRGB = z == 3;
            imshow(img, 'parent', app.UIAxes);
        end

        % Button pushed function: ShowHistogramInputButton
        function ShowHistogramInputButtonPushed(app, event)
            if app.isImageRGB
                rgbHist(app.Image)
            else
                gsHist(app.Image)
            end
        end

        % Button pushed function: BrightenButton
        function BrightenButtonPushed(app, event)
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            result = imageBrightening(app.Image,a,b);
            app.Output = result;
            imshow(result, 'parent', app.UIAxes_2);
        end

        % Button pushed function: ShowHistogramOutputButton
        function ShowHistogramOutputButtonPushed(app, event)
            if app.isImageRGB
                rgbHist(app.Output)
            else
                gsHist(app.Output)
            end
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            r = app.rEditField.Value;
            c = app.cEditField.Value;
            result = logTransform(app.Image,c,r);
            app.Output = result;
            imshow(result, 'parent', app.UIAxes_2);
        end

        % Button pushed function: TransformButton_2
        function TransformButton_2Pushed(app, event)
            c = app.cEditField_2.Value;
            g = app.gammaEditField.Value;
            result = imageBrightening(app.Image,c,g);
            app.Output = result;
            imshow(result, 'parent', app.UIAxes_2);
        end

        % Button pushed function: CitraNegatifButton
        function CitraNegatifButtonPushed(app, event)
            result = negativeImage(app.Image);
            app.Output = result;
            imshow(result, 'parent', app.UIAxes_2);
        end

        % Button pushed function: ContrastStretchingButton
        function ContrastStretchingButtonPushed(app, event)
            result = contrastStretching(app.Image);
            app.Output = result;
            imshow(result, 'parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Result')
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnhancementTab = uitab(app.TabGroup);
            app.EnhancementTab.Title = 'Enhancement';
            app.ImageBrighteningPanel = uipanel(app.EnhancementTab);
            app.ImageBrighteningPanel.Title = 'Image Brightening';
            app.aEditFieldLabel = uilabel(app.ImageBrighteningPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ImageBrighteningPanel, 'numeric');
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.ImageBrighteningPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ImageBrighteningPanel, 'numeric');
            app.BrightenButton = uibutton(app.ImageBrighteningPanel, 'push');
            app.BrightenButton.ButtonPushedFcn = createCallbackFcn(app, @BrightenButtonPushed, true);
            app.BrightenButton.Text = 'Brighten!';
            app.TransformasiLogPanel = uipanel(app.EnhancementTab);
            app.TransformasiLogPanel.Title = 'Transformasi Log';
            app.cEditFieldLabel = uilabel(app.TransformasiLogPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.TransformasiLogPanel, 'numeric');
            app.rEditFieldLabel = uilabel(app.TransformasiLogPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.TransformasiLogPanel, 'numeric');
            app.TransformButton = uibutton(app.TransformasiLogPanel, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform!';
            app.TransformasiPangkatPanel = uipanel(app.EnhancementTab);
            app.TransformasiPangkatPanel.Title = 'Transformasi Pangkat';
            app.cEditField_2Label = uilabel(app.TransformasiPangkatPanel);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.TransformasiPangkatPanel, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.TransformasiPangkatPanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.TransformasiPangkatPanel, 'numeric');
            app.TransformButton_2 = uibutton(app.TransformasiPangkatPanel, 'push');
            app.TransformButton_2.ButtonPushedFcn = createCallbackFcn(app, @TransformButton_2Pushed, true);
            app.TransformButton_2.Text = 'Transform!';
            app.CitraNegatifButton = uibutton(app.EnhancementTab, 'push');
            app.CitraNegatifButton.ButtonPushedFcn = createCallbackFcn(app, @CitraNegatifButtonPushed, true);
            app.CitraNegatifButton.Text = 'Citra Negatif';
            app.ContrastStretchingButton = uibutton(app.EnhancementTab, 'push');
            app.ContrastStretchingButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastStretchingButtonPushed, true);
            app.ContrastStretchingButton.Text = 'Contrast Stretching';
            app.HistEqTab = uitab(app.TabGroup);
            app.HistEqTab.Title = 'Hist Eq';
            app.StartHistogramEqualizationButton = uibutton(app.HistEqTab, 'push');
            app.StartHistogramEqualizationButton.Text = 'Start Histogram Equalization';
            app.HistSpecTab = uitab(app.TabGroup);
            app.HistSpecTab.Title = 'Hist Spec';
            app.BrowseReferenceImageButton = uibutton(app.HistSpecTab, 'push');
            app.BrowseReferenceImageButton.Text = 'Browse Reference Image...';
            app.StartHistogramSpecificationButton = uibutton(app.HistSpecTab, 'push');
            app.StartHistogramSpecificationButton.Text = 'Start Histogram Specification';
            app.ShowHistogramInputButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramInputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramInputButtonPushed, true);
            app.ShowHistogramInputButton.Text = 'Show Histogram Input';
            app.ShowHistogramOutputButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramOutputButtonPushed, true);
            app.ShowHistogramOutputButton.Text = 'Show Histogram Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end